classdef project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        DetectTumorButton         matlab.ui.control.Button
        TumorAloneButton          matlab.ui.control.Button
        UploadButton              matlab.ui.control.Button
        BRAINTUMORDETECTIONLabel  matlab.ui.control.Label
        UIAxes3                   matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img1 
        img
        tumor
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            [filename,pathname]=uigetfile('*','Pick an Image');
            filename=strcat(pathname,filename);
            app.img1=imread(filename);
            imshow(app.img1,'Parent',app.UIAxes)
        end

        % Button pushed function: TumorAloneButton
        function TumorAloneButtonPushed(app, event)
           app.img=im2gray(app.img1);
            bw=imbinarize(app.img,0.7);
            %To find all the white regions in the binarized image
            label=bwlabel(bw); 
            
            %Gets the Area to detect the no. of pixels and solidity is for finding convexity
            stats=regionprops(label,'Solidity','Area');  
            density=[stats.Solidity];
            area=[stats.Area];

            %Fetches all the regions having density greater than 0.5 
            high_dense_area=density>0.5;

            %Finds the max area of the highest solidity
            max_area=max(area(high_dense_area));
            tumor_label=find(area==max_area);

            %Displays the matrix of the binarized image of tumor 
            app.tumor=ismember(label,tumor_label);

            % Dilation is a morphological operation that expands the white regions, helping to fill gaps and make the tumor region(s) more connected.       
            se=strel('square',5);
            app.tumor=imdilate(app.tumor,se);
            imshow(app.tumor,'Parent',app.UIAxes2);
        end

        % Button pushed function: DetectTumorButton
        function DetectTumorButtonPushed(app, event)
            %Highlights the tumor region
            [B]=bwboundaries(app.tumor,'noholes');
            imshow(app.img,'Parent',app.UIAxes3);
            hold(app.UIAxes3, 'on'); % Hold on UIAxes3
            for i=1:length(B)
                plot(app.UIAxes3, B{i}(:,2), B{i}(:,1), 'y' ,'linewidth',1.45);
            end
            hold(app.UIAxes3, 'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Brain')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Tumor Alone')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Detected Tumor')
            app.BRAINTUMORDETECTIONLabel = uilabel(app.UIFigure);
            app.BRAINTUMORDETECTIONLabel.Text = 'BRAIN TUMOR DETECTION';
            app.UploadButton = uibutton(app.UIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.TumorAloneButton = uibutton(app.UIFigure, 'push');
            app.TumorAloneButton.ButtonPushedFcn = createCallbackFcn(app, @TumorAloneButtonPushed, true);
            app.TumorAloneButton.Text = 'Tumor Alone';
            app.DetectTumorButton = uibutton(app.UIFigure, 'push');
            app.DetectTumorButton.ButtonPushedFcn = createCallbackFcn(app, @DetectTumorButtonPushed, true);
            app.DetectTumorButton.Text = 'Detect Tumor';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end